classdef rosma_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        dVRKDATASETEXPERIMENTSLabel   matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        SelectuserDropDownLabel       matlab.ui.control.Label
        SelectuserDropDown            matlab.ui.control.DropDown
        Image2                        matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
        NewUserPanel                  matlab.ui.container.Panel
        AliasEditFieldLabel           matlab.ui.control.Label
        AliasEditField                matlab.ui.control.EditField
        NameEditFieldLabel            matlab.ui.control.Label
        NameEditField                 matlab.ui.control.EditField
        GenderDropDownLabel           matlab.ui.control.Label
        GenderDropDown                matlab.ui.control.DropDown
        SkilllevelDropDownLabel       matlab.ui.control.Label
        SkilllevelDropDown            matlab.ui.control.DropDown
        AgeEditFieldLabel             matlab.ui.control.Label
        AgeEditField                  matlab.ui.control.NumericEditField
        ERRORPleasecompleteallfieldsLabel  matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Panel_2                       matlab.ui.container.Panel
        Button_3                      matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        ExperimenthistoryLabel        matlab.ui.control.Label
        SelecttaskDropDownLabel       matlab.ui.control.Label
        SelecttaskDropDown            matlab.ui.control.DropDown
        StartRecordingButton          matlab.ui.control.Button
        StopRecordingButton           matlab.ui.control.Button
        EditFieldLabel                matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        SAVEButton                    matlab.ui.control.Button
        DELETEButton                  matlab.ui.control.Button
        NumberoferrrosEditFieldLabel  matlab.ui.control.Label
        NumberoferrrosEditField       matlab.ui.control.NumericEditField
    end

  
    properties (Access = public)
        UsersData % Stores users info and number of trials of each experiment
        performanceData % Stores the time and number of errors of the users' trials. 
        stop = 0; % set to 1 to stop the data recording.
        start = 0; % set to 1 to start the data recording
        currentExperiment % stores data of the current experiment being performed. 
        pub % ros publishers.
    end
    
    
    methods (Access = private)
        
        function creatematFile(app)
                % UsersData variable
                UsersData = table;
                UsersData.Id = {};
                UsersData.Alias = {};
                UsersData.Name = {};
                UsersData.Gender = {};
                UsersData.Age = {};
                UsersData.Skills = {};
                for i = 2:1:length(app.SelecttaskDropDown.Items)    
                    eval(['UsersData.',app.SelecttaskDropDown.Items{i},' = {};'])
                end
                
                % PerformanceData variable
                performanceData = struct; 

                save('ExperimentInfo.mat','UsersData','performanceData');
                app.UsersData = UsersData;
                app.performanceData = performanceData;
        end
        
        function clearUserInfo(app)
            app.AliasEditField.Value = '';
            app.NameEditField.Value = '';
            app.GenderDropDown.Value = '-';
            app.AgeEditField.Value = 0;
            app.SkilllevelDropDown.Value = '-';
        end
        
        function results = checkFields(app)
            if strcmp(app.AliasEditField.Value,'') || strcmp(app.NameEditField.Value,'') || strcmp(app.GenderDropDown,'-') || strcmp(app.AgeEditField.Value,'') || strcmp(app.SkilllevelDropDown.Value,'-')
                results = 0;
            else 
                results = 1;
            end    
        end
        
        function loadUsers(app)
            string = app.SelectuserDropDown.Items;
            [~,m] = size(string);
            [n,~] = size(app.UsersData); 
            j = 1;
            for i = m+1:1:n+m
                 string{i} = app.UsersData.Alias{j};
                 j = j+1;
            end 
            app.SelectuserDropDown.Items = string;
        end
        
        function updateUserDropDown(app)
            string = app.SelectuserDropDown.Items;
            [~,m] = size(string);
            string{m+1} = app.UsersData.Alias{end};       
            app.SelectuserDropDown.Items = string;  
        end
        
        function loadHistory(app)
            app.UITable.Data(:,2) = app.UsersData{app.currentExperiment.n,7:15};    
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            warning('off','MATLAB:table:RowsAddedExistingVars')
            % ros init
            if ~ros.internal.Global.isNodeActive
                rosinit;
            end
             
            % Hide panels
            app.NewUserPanel.Visible = 'off';
            app.Panel_2.Visible = 'off';
            app.StartRecordingButton.Enable = 'off';
            app.StopRecordingButton.Enable = 'off';
            app.EditField.Visible = 'off';
            app.SAVEButton.Visible = 'off';
            app.DELETEButton.Visible = 'off';
            app.StartRecordingButton.Visible = 'off';
            app.StopRecordingButton.Visible = 'off';
            app.EditField.Visible = 'off';
            app.NumberoferrrosEditField.Visible = 'off';
            app.NumberoferrrosEditFieldLabel.Visible = 'off';
            
            % Experiment history table
            app.UITable.ColumnName = {'Task name','#Repetitions'};
            app.UITable.Data = {'Clipping',0; 'Pea on a Peg',0; ' Wire Chaser I',0; ...
                'Post and Sleeve',0; 'Wire Chaser II',0; 'Pattern Cutting',0;...
                'Holiotomy',0; 'Knot Tying',0; 'Suturing',0};      
            % Tasks drop down
            app.SelecttaskDropDown.Items =  {'-';'Clipping'; 'Pea_on_a_Peg'; ' Wire_Chaser_I'; ...
                'Post_and_Sleeve'; 'Wire_Chaser_II'; 'Pattern_Cutting';...
                'Holiotomy'; 'Knot_Tying'; 'Suturing'};
            app.SelecttaskDropDown.Value = {'-'};
            
            % Create files and folders if not exist
            if ~isfile('ExperimentInfo.mat') 
                creatematFile(app); 
            else 
                load ExperimentInfo.mat
                app.UsersData = UsersData; 
                app.performanceData = performanceData;
            end
            
            % Loading users name in selector
            loadUsers(app)
            
        end

        % Value changed function: SelectuserDropDown
        function SelectuserDropDownValueChanged(app, event)
            value = app.SelectuserDropDown.Value;
            app.SelectuserDropDown.Enable = 'off';
            switch value
                case 'New user'                
                    app.NewUserPanel.Visible = 'on';
                    app.SelectuserDropDown.Value = '-';
                otherwise
                    app.Panel_2.Visible = 'on';
                    % getting current user id
                    app.currentExperiment.n = find(strcmp(app.UsersData.Alias,value));
                    app.currentExperiment.id = app.UsersData.Id{app.currentExperiment.n};
                    loadHistory(app)
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % SAVE NEW USER INFO BUTTON
            
            % Reading user info
            alias = app.AliasEditField.Value;
            name = app.NameEditField.Value;
            age = app.AgeEditField.Value;
            gender = app.GenderDropDown.Value;
            skill = app.SkilllevelDropDown.Value;
            
            % Checking if all fields are completed
            if ~checkFields(app)
                app.ERRORPleasecompleteallfieldsLabel.Visible = 'on';
            else 
                app.ERRORPleasecompleteallfieldsLabel.Visible = 'off';
            end
            
            % Storing user info in UsersData.mat
            UsersData = app.UsersData;
            performanceData = app.performanceData;
            [n,~] = size(UsersData);
            id = ['U',num2str(n+1,'%02.f')];
            UsersData.Id{n+1} = id;
            UsersData.Alias{n+1} = alias;
            UsersData.Name{n+1} = name;
            UsersData.Age{n+1} = age;
            UsersData.Gender{n+1} = gender;
            UsersData.Skills{n+1} = skill;
            for i = 2:1:length(app.SelecttaskDropDown.Items)
                 eval(['UsersData.',app.SelecttaskDropDown.Items{i},'{n+1} = 0;'])
            end
            
            % If all fields completed
            if checkFields(app)        
                eval(['performanceData.',id,'= []']) % create user variable in peformanceData
                app.UsersData = UsersData;
                app.performanceData = performanceData;
                save('ExperimentInfo.mat','UsersData','performanceData','-append')
                app.NewUserPanel.Visible = 'off';
                app.SelectuserDropDown.Enable = 'on';
                updateUserDropDown(app)
                clearUserInfo(app)              
                display(UsersData)
            end
                    
        end

        % Button pushed function: Button
        function Button_3Pushed(app, event)
            % BACK BUTTON IN NUEW USER PANEL
            app.NewUserPanel.Visible = 'off';
            app.SelectuserDropDown.Enable = 'on';
            clearUserInfo(app);
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed2(app, event)
            % BACK BUTTON IN EXPERIMENT PANEL
            app.Panel_2.Visible = 'off';
            app.SelectuserDropDown.Value = '-';
            app.SelectuserDropDown.Enable = 'on';
            app.SAVEButton.Visible = 'off';
            app.DELETEButton.Visible = 'off';
            app.StopRecordingButton.Enable = 'off';
            app.StartRecordingButton.Enable = 'off';
            app.EditField.Value = '';
            app.SelecttaskDropDown.Value = {'-'};
            app.EditField.Visible = 'off';
            app.StartRecordingButton.Visible = 'off';
            app.StopRecordingButton.Visible = 'off';
            app.NumberoferrrosEditField.Visible = 'off';
            app.NumberoferrrosEditFieldLabel.Visible = 'off';
        end

        % Value changed function: SelecttaskDropDown
        function SelecttaskDropDownValueChanged(app, event)
            app.currentExperiment.task = app.SelecttaskDropDown.Value;
            app.StartRecordingButton.Visible = 'on';
            app.StartRecordingButton.Enable = 'on';
            
           % Checking rosma node running
           rnode = rosnode("list");
           while isempty(find(strcmp(rnode,'/rosma')))
               waitfor(msgbox({'Please launch rosma node and press OK';'';'';'$rosrun rosmadataset rosmadaset'}))
               rnode = rosnode("list");
               pause(0.1)
           end
                               
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            app.StartRecordingButton.Enable = 'off';
            display('Start recording')           
            app.StopRecordingButton.Visible = 'on';
            app.StopRecordingButton.Enable = 'on';
            app.EditField.Visible = 'on';
            app.stop = 0;    
            
            tic
            while(~app.stop)
                t_hms = datevec(toc./(60*60*24));
                app.EditField.Value = ['Time:',' ',num2str(t_hms(5)),' ','min',' ',num2str(t_hms(6)),' ','sec'];
                pause(0.1);
            end
            app.currentExperiment.time = t_hms(5)*60+t_hms(6);         
        end

        % Button pushed function: StopRecordingButton
        function StopRecordingButtonPushed(app, event)
            disp('Stop Recording')
            app.stop = 1;   
            app.start = 0;
            app.SAVEButton.Visible = 'on';
            app.DELETEButton.Visible = 'on';
            app.NumberoferrrosEditField.Visible = 'on';
            app.NumberoferrrosEditFieldLabel.Visible = 'on';
            
            %%% Send topic to stop recording
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            % update experiment trial in UsersData
            n = app.currentExperiment.n;
            eval(['app.UsersData.' app.currentExperiment.task '{n} = app.UsersData.' app.currentExperiment.task '{n} + 1;'])
            loadHistory(app)
            
            % Record trial info in performanceData
            eval(['trial = app.UsersData.' app.currentExperiment.task '{n};'])
            eval(['app.performanceData.' app.currentExperiment.id '.time.' app.currentExperiment.task '(trial) = app.currentExperiment.time;'])
            eval(['app.performanceData.' app.currentExperiment.id '.errors.' app.currentExperiment.task '(trial) = app.NumberoferrrosEditField.Value;'])   
            
            % save in mat file
            UsersData = app.UsersData;
            performanceData = app.performanceData;
            save('ExperimentInfo.mat','UsersData','performanceData','-append')
                    
            app.EditField.Visible = 'off';
            app.StopRecordingButton.Visible = 'off';
            app.StartRecordingButton.Enable = 'on';
            app.SAVEButton.Visible = 'off';
            app.DELETEButton.Visible = 'off';
            app.NumberoferrrosEditField.Visible = 'off';
            app.NumberoferrrosEditFieldLabel.Visible = 'off';
            app.NumberoferrrosEditField.Value = 0;
        end

        % Button pushed function: DELETEButton
        function DELETEButtonPushed(app, event)
            app.EditField.Visible = 'off';
            app.StopRecordingButton.Visible = 'off';
            app.StartRecordingButton.Enable = 'on';
            app.SAVEButton.Visible = 'off';
            app.DELETEButton.Visible = 'off';    
            app.NumberoferrrosEditField.Visible = 'off';
            app.NumberoferrrosEditFieldLabel.Visible = 'off';
            app.NumberoferrrosEditField.Value = 0;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            rosshutdown;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.dVRKDATASETEXPERIMENTSLabel = uilabel(app.UIFigure);
            app.dVRKDATASETEXPERIMENTSLabel.Text = 'dVRK DATASET EXPERIMENTS';
            app.Panel = uipanel(app.UIFigure);
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'dvrk.png';
            app.SelectuserDropDownLabel = uilabel(app.Panel);
            app.SelectuserDropDownLabel.Text = {'Select user'; ''; ''; ''; ''};
            app.SelectuserDropDown = uidropdown(app.Panel);
            app.SelectuserDropDown.Items = {'-', 'New user'};
            app.SelectuserDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectuserDropDownValueChanged, true);
            app.SelectuserDropDown.Value = '-';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'logo_santanna.jpeg';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'logo_uma.jpeg';
            app.NewUserPanel = uipanel(app.UIFigure);
            app.NewUserPanel.Title = 'New User';
            app.AliasEditFieldLabel = uilabel(app.NewUserPanel);
            app.AliasEditFieldLabel.Text = 'Alias';
            app.AliasEditField = uieditfield(app.NewUserPanel, 'text');
            app.NameEditFieldLabel = uilabel(app.NewUserPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.NewUserPanel, 'text');
            app.GenderDropDownLabel = uilabel(app.NewUserPanel);
            app.GenderDropDownLabel.Text = {'Gender'; ''};
            app.GenderDropDown = uidropdown(app.NewUserPanel);
            app.GenderDropDown.Items = {'-', 'Male', 'Female'};
            app.GenderDropDown.Value = '-';
            app.SkilllevelDropDownLabel = uilabel(app.NewUserPanel);
            app.SkilllevelDropDownLabel.Text = 'Skill level';
            app.SkilllevelDropDown = uidropdown(app.NewUserPanel);
            app.SkilllevelDropDown.Items = {'-', 'Technician', 'Surgeon'};
            app.SkilllevelDropDown.Value = '-';
            app.AgeEditFieldLabel = uilabel(app.NewUserPanel);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.NewUserPanel, 'numeric');
            app.ERRORPleasecompleteallfieldsLabel = uilabel(app.NewUserPanel);
            app.ERRORPleasecompleteallfieldsLabel.Visible = 'off';
            app.ERRORPleasecompleteallfieldsLabel.Text = 'ERROR: Please complete all fields';
            app.Button = uibutton(app.NewUserPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button.Icon = 'back2.png';
            app.Button.Text = {''; ''; ''};
            app.Button_2 = uibutton(app.NewUserPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'save.png';
            app.Button_2.Text = {''; ''};
            app.Panel_2 = uipanel(app.UIFigure);
            app.Button_3 = uibutton(app.Panel_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed2, true);
            app.Button_3.Icon = 'back2.png';
            app.Button_3.Text = {''; ''; ''};
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {''};
            app.ExperimenthistoryLabel = uilabel(app.Panel_2);
            app.ExperimenthistoryLabel.Text = 'Experiment history';
            app.SelecttaskDropDownLabel = uilabel(app.Panel_2);
            app.SelecttaskDropDownLabel.Text = 'Select task';
            app.SelecttaskDropDown = uidropdown(app.Panel_2);
            app.SelecttaskDropDown.Items = {};
            app.SelecttaskDropDown.ValueChangedFcn = createCallbackFcn(app, @SelecttaskDropDownValueChanged, true);
            app.SelecttaskDropDown.Value = {};
            app.StartRecordingButton = uibutton(app.Panel_2, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.StopRecordingButton = uibutton(app.Panel_2, 'push');
            app.StopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordingButtonPushed, true);
            app.StopRecordingButton.Text = 'Stop Recording';
            app.EditFieldLabel = uilabel(app.Panel_2);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.Panel_2, 'text');
            app.EditField.Editable = 'off';
            app.SAVEButton = uibutton(app.Panel_2, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.DELETEButton = uibutton(app.Panel_2, 'push');
            app.DELETEButton.ButtonPushedFcn = createCallbackFcn(app, @DELETEButtonPushed, true);
            app.DELETEButton.Text = 'DELETE';
            app.NumberoferrrosEditFieldLabel = uilabel(app.Panel_2);
            app.NumberoferrrosEditFieldLabel.Text = 'Number of errros';
            app.NumberoferrrosEditField = uieditfield(app.Panel_2, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rosma_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end